classdef scopeInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure                   % nScope Ma...
        scopePlot            matlab.ui.control.UIAxes           % Scope
        channelPanel         matlab.ui.container.Panel          % Channels
        channelOneCheckBox   matlab.ui.control.CheckBox         % Channel 1
        channelTwoCheckBox   matlab.ui.control.CheckBox         % Channel 2
        channelThreeCheckBox matlab.ui.control.CheckBox         % Channel 3
        channelFourCheckBox  matlab.ui.control.CheckBox         % Channel 4
        statusPanel          matlab.ui.container.Panel          % Status
        LabelLamp            matlab.ui.control.Label            % Connection
        connectionLamp       matlab.ui.control.Lamp            
        LabelLamp2           matlab.ui.control.Label            % Power Supply
        Lamp                 matlab.ui.control.Lamp            
        controlPanel         matlab.ui.container.Panel          % Controls
        timeSlider           matlab.ui.control.Slider           % [1.000000...
        LabelSlider          matlab.ui.control.Label            % Time Sweep
        timeField            matlab.ui.control.NumericEditField % [-Inf Inf]
        voltageSlider        matlab.ui.control.Slider           % [1.000000...
        Label                matlab.ui.control.Label            % Voltage S...
        voltageField         matlab.ui.control.NumericEditField % [-Inf Inf]
        voltageBiasSlider    matlab.ui.control.Slider           % [-5 5]
        Label5               matlab.ui.control.Label            % Voltage Bias
        voltageBiasField     matlab.ui.control.NumericEditField % [-Inf Inf]
        pauseButton          matlab.ui.control.StateButton      % Pause
        peakVoltageTwo       matlab.ui.control.NumericEditField % [-Inf Inf]
        peakVoltageFour      matlab.ui.control.NumericEditField % [-Inf Inf]
        peakVoltageOne       matlab.ui.control.NumericEditField % [-Inf Inf]
        peakVoltageThree     matlab.ui.control.NumericEditField % [-Inf Inf]
        minVoltageTwo        matlab.ui.control.NumericEditField % [-Inf Inf]
        minVoltageFour       matlab.ui.control.NumericEditField % [-Inf Inf]
        minVoltageOne        matlab.ui.control.NumericEditField % [-Inf Inf]
        minVoltageThree      matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel                matlab.ui.container.Panel          % Extrema
        Label6               matlab.ui.control.Label            % Max Voltage
        Label7               matlab.ui.control.Label            % Min Voltage
        Label8               matlab.ui.control.Label            % Ch1
        Label9               matlab.ui.control.Label            % Ch2
        Label10              matlab.ui.control.Label            % Ch3
        Label11              matlab.ui.control.Label            % Ch4
    end

    %% nScope Matlab Interface
    %  Adam Zeloof
    %  10/8/2016
    %  Version alpha-0.3
    
    properties (Access = private)
        sampleRate
        timeSpan
        voltageSpan
        gain
        numSamples
        liveData
        recData
        recording
        recCounter
        timeSamples
        running
        isConnected
        plotCounter
        voltageBias
        ch1
        ch2
        ch3
        ch4
        plotDeadSpace
    end
    
    methods (Access = private)
        
        function status = connectScope(app, trys)
            delay = 5; %seconds
            if (trys > 0)
                try
                    nScopeAPI('open',1);
                    status = 1;
                catch
                    disp('Scope not found, trying again in 5 seconds...');
                    pause(delay);
                    app.connectScope(trys-1);
                    status = 0;
                end
            else
                disp('No scope was found, giving up.')
                status = 0;
            end
        end
        
        function setupScope(app)
            app.ch1 = app.channelOneCheckBox.Value;
            app.ch2 = app.channelTwoCheckBox.Value;
            app.ch3 = app.channelThreeCheckBox.Value;
            app.ch4 = app.channelFourCheckBox.Value;
            if app.isConnected
                nScopeAPI('setChannelsOn',app.ch1,app.ch2,app.ch3,app.ch4);         % turn on Channel 1, and no others
                nScopeAPI('setCh1gain',app.gain);                  % set the gain on Channel 1
                nScopeAPI('setSampleRateInHz',app.sampleRate);          % set the sample rate
                nScopeAPI('requestData', app.numSamples);       % request the samples from nScope
            end
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            app.scopePlot.XLim = [0, app.timeSpan];
        end
        
        function getAndPlot(app)
            if(app.isConnected && nScopeAPI('requestHasData'))
                if app.running
                    app.plotCounter = app.plotCounter + 1;
                    if app.ch1
                        app.liveData(app.plotCounter, 1) = nScopeAPI('readData', 1);
                        app.peakVoltageOne.Value = max(app.liveData(:,1));
                        app.minVoltageOne.Value = min(app.liveData(:,1));
                    end
                    if app.ch2
                        app.liveData(app.plotCounter, 2) = nScopeAPI('readData', 2);
                        app.peakVoltageTwo.Value = max(app.liveData(:,2));
                        app.minVoltageTwo.Value = min(app.liveData(:,2));
                    end
                    if app.ch3
                        app.liveData(app.plotCounter, 3) = nScopeAPI('readData', 3);
                        app.peakVoltageThree.Value = max(app.liveData(:,3));
                        app.minVoltageThree.Value = min(app.liveData(:,3));
                    end
                    if app.ch4
                        app.liveData(app.plotCounter, 4) = nScopeAPI('readData', 4);
                        app.peakVoltageFour.Value = max(app.liveData(:,4));
                        app.minVoltageFour.Value = min(app.liveData(:,4));
                    end
                    if app.plotCounter + 1 < app.numSamples
                        app.liveData(app.plotCounter + 1) = NaN;
                    end
                    plot(app.scopePlot, app.timeSamples, app.liveData);
                end
            else
                app.plotCounter = 0;
                nScopeAPI('requestData', app.numSamples);
            end
        end
        
        function updateSettings(app)
            app.running = false;
            app.sampleRate = 25; %hz
            app.timeSpan = app.timeSlider.Value; %seconds
            app.voltageSpan = app.voltageSlider.Value;
            app.voltageBias = app.voltageBiasSlider.Value;
            app.gain = 1;
            app.numSamples = round(app.sampleRate * app.timeSpan);
            app.liveData = NaN(app.numSamples,4);
            app.timeSamples = linspace(0, app.timeSpan, app.numSamples);
            app.plotCounter = 0;
            app.setupScope();
            app.running = true;
        end
        
        function run(app)
            timer = tic;
            oldTime = 0;
            delta = 1/app.sampleRate;
            app.plotCounter = 0;
            try
                while (app.isConnected)
                    time = toc(timer);
                    if time > (oldTime + delta)
                        oldTime = time;
                        if app.running
                            app.getAndPlot();
                        end
                        pause(.01);
                    end
                end
            catch
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.running = 1;
            app.isConnected = 0;
            if app.connectScope(5)
                app.isConnected = 1;
                app.connectionLamp.Enable = 'on';
                app.updateSettings();
                app.run();
            end
        end

        % channelOneCheckBox value changed function
        function ch1Val(app)
            value = app.channelOneCheckBox.Value;
            app.updateSettings();
        end

        % channelTwoCheckBox value changed function
        function ch2Val(app)
            value = app.channelTwoCheckBox.Value;
            app.updateSettings();
        end

        % channelThreeCheckBox value changed function
        function ch3Val(app)
            value = app.channelThreeCheckBox.Value;
            app.updateSettings();
        end

        % channelFourCheckBox value changed function
        function ch4Val(app)
            value = app.channelFourCheckBox.Value;
            app.updateSettings();
        end

        % timeSlider value changed function
        function timeSliderChange(app)
            value = app.timeSlider.Value;
            app.timeField.Value = value;
            app.timeSpan = value;
            app.updateSettings();
        end

        % timeField value changed function
        function timeFieldChange(app)
            value = app.timeField.Value;
            app.timeSlider.Value = value;
            app.timeSpan = value;
            app.updateSettings();
        end

        % voltageSlider value changed function
        function voltageSliderChange(app)
            value = app.voltageSlider.Value;
            app.voltageField.Value = value;
            app.voltageSpan = value;
            app.voltageSpan = app.voltageSlider.Value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            %app.updateSettings();
        end

        % voltageField value changed function
        function voltageFieldChange(app)
            value = app.voltageField.Value;
            app.voltageSlider.Value = value;
            app.voltageSpan = value;
            app.voltageSpan = app.voltageSlider.Value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
            %app.updateSettings();
        end

        % UIFigure close request function
        function shutdown(app)
            app.running = 0;
            app.isConnected = 0;
            try
                nScopeAPI('close', 1);
            catch
                disp('Tried to close scope, failed.');
            end
            pause(.1);
            app.delete();
        end

        % voltageBiasSlider value changed function
        function voltageBiasSliderChange(app)
            value = app.voltageBiasSlider.Value;
            app.voltageBiasField.Value = value;
            app.voltageBias = value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
        end

        % voltageBiasField value changed function
        function voltageBiasFieldChange(app)
            value = app.voltageBiasField.Value;
            app.voltageBiasSlider.Value = value;
            app.voltageBias = value;
            app.scopePlot.YLim = [-app.voltageSpan+app.voltageBias,app.voltageSpan+app.voltageBias];
        end

        % pauseButton value changed function
        function pauseResume(app)
            value = ~ app.pauseButton.Value;
            app.running = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'nScope Matlab Interface';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @shutdown);
            setAutoResize(app, app.UIFigure, true)
            app.scopePlot = uiaxes(app.UIFigure);
            title(app.scopePlot, 'Scope');
            xlabel(app.scopePlot, 'Time (sec)');
            ylabel(app.scopePlot, 'Voltage');
            app.channelPanel = uipanel(app.UIFigure);
            app.channelPanel.Title = 'Channels';
            app.channelPanel.Units = 'pixels';
            app.channelOneCheckBox = uicheckbox(app.channelPanel);
            app.channelOneCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch1Val);
            app.channelOneCheckBox.Text = 'Channel 1';
            app.channelOneCheckBox.Value = true;
            app.channelTwoCheckBox = uicheckbox(app.channelPanel);
            app.channelTwoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch2Val);
            app.channelTwoCheckBox.Text = 'Channel 2';
            app.channelThreeCheckBox = uicheckbox(app.channelPanel);
            app.channelThreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch3Val);
            app.channelThreeCheckBox.Text = 'Channel 3';
            app.channelFourCheckBox = uicheckbox(app.channelPanel);
            app.channelFourCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch4Val);
            app.channelFourCheckBox.Text = 'Channel 4';
            app.statusPanel = uipanel(app.UIFigure);
            app.statusPanel.Title = 'Status';
            app.statusPanel.Units = 'pixels';
            app.LabelLamp = uilabel(app.statusPanel);
            app.LabelLamp.Text = 'Connection';
            app.connectionLamp = uilamp(app.statusPanel);
            app.connectionLamp.Enable = 'off';
            app.LabelLamp2 = uilabel(app.statusPanel);
            app.LabelLamp2.Text = 'Power Supply';
            app.Lamp = uilamp(app.statusPanel);
            app.Lamp.Enable = 'off';
            app.controlPanel = uipanel(app.UIFigure);
            app.controlPanel.Title = 'Controls';
            app.controlPanel.Units = 'pixels';
            app.timeSlider = uislider(app.controlPanel);
            app.timeSlider.Limits = [0.01 10];
            app.timeSlider.MajorTickLabels = {'0', '', '', '', '', '10'};
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderChange);
            app.timeSlider.Value = 1;
            app.LabelSlider = uilabel(app.controlPanel);
            app.LabelSlider.Text = 'Time Sweep';
            app.timeField = uieditfield(app.controlPanel, 'numeric');
            app.timeField.ValueChangedFcn = createCallbackFcn(app, @timeFieldChange);
            app.timeField.Value = 1;
            app.voltageSlider = uislider(app.controlPanel);
            app.voltageSlider.Limits = [0.01 6];
            app.voltageSlider.MajorTickLabels = {'0', '', '', '', '', '', '6'};
            app.voltageSlider.ValueChangedFcn = createCallbackFcn(app, @voltageSliderChange);
            app.voltageSlider.Value = 5;
            app.Label = uilabel(app.controlPanel);
            app.Label.Text = 'Voltage Scale';
            app.voltageField = uieditfield(app.controlPanel, 'numeric');
            app.voltageField.ValueChangedFcn = createCallbackFcn(app, @voltageFieldChange);
            app.voltageField.Value = 5;
            app.voltageBiasSlider = uislider(app.controlPanel);
            app.voltageBiasSlider.Limits = [-5 5];
            app.voltageBiasSlider.MajorTicks = [-5 0 5];
            app.voltageBiasSlider.ValueChangedFcn = createCallbackFcn(app, @voltageBiasSliderChange);
            app.Label5 = uilabel(app.controlPanel);
            app.Label5.Text = 'Voltage Bias';
            app.voltageBiasField = uieditfield(app.controlPanel, 'numeric');
            app.voltageBiasField.ValueChangedFcn = createCallbackFcn(app, @voltageBiasFieldChange);
            app.pauseButton = uibutton(app.UIFigure, 'state');
            app.pauseButton.ValueChangedFcn = createCallbackFcn(app, @pauseResume);
            app.pauseButton.Text = 'Pause';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Extrema';
            app.Panel.Units = 'pixels';
            app.minVoltageTwo = uieditfield(app.Panel, 'numeric');
            app.minVoltageTwo.Editable = 'off';
            app.minVoltageFour = uieditfield(app.Panel, 'numeric');
            app.minVoltageFour.Editable = 'off';
            app.minVoltageOne = uieditfield(app.Panel, 'numeric');
            app.minVoltageOne.Editable = 'off';
            app.minVoltageThree = uieditfield(app.Panel, 'numeric');
            app.minVoltageThree.Editable = 'off';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'Max Voltage';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Text = 'Min Voltage';
            app.Label8 = uilabel(app.Panel);
            app.Label8.Text = 'Ch1';
            app.Label9 = uilabel(app.Panel);
            app.Label9.Text = 'Ch2';
            app.Label10 = uilabel(app.Panel);
            app.Label10.Text = 'Ch3';
            app.Label11 = uilabel(app.Panel);
            app.Label11.Text = 'Ch4';
            app.peakVoltageTwo = uieditfield(app.Panel, 'numeric');
            app.peakVoltageTwo.Editable = 'off';
            app.peakVoltageFour = uieditfield(app.Panel, 'numeric');
            app.peakVoltageFour.Editable = 'off';
            app.peakVoltageOne = uieditfield(app.Panel, 'numeric');
            app.peakVoltageOne.Editable = 'off';
            app.peakVoltageThree = uieditfield(app.Panel, 'numeric');
            app.peakVoltageThree.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = scopeInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end